classdef app < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure        matlab.ui.Figure
        TabGroup        matlab.ui.container.TabGroup
        DebugTab        matlab.ui.container.Tab
        PortDropDown    matlab.ui.control.DropDown
        Label           matlab.ui.control.Label
        SyncLamp        matlab.ui.control.Lamp
        GPIOLampLabel   matlab.ui.control.Label
        ConnectButton   matlab.ui.control.Button
        StatusLabel     matlab.ui.control.Label
        StopButton      matlab.ui.control.Button
        StartButton     matlab.ui.control.Button
        UIAxes          matlab.ui.control.UIAxes
        ExperimentTab   matlab.ui.container.Tab
        StopButton_2    matlab.ui.control.Button
        StartButton_2   matlab.ui.control.Button
        MvcEditField    matlab.ui.control.NumericEditField
        Label_4         matlab.ui.control.Label
        ModeDropDown    matlab.ui.control.DropDown
        Label_3         matlab.ui.control.Label
        FingerDropDown  matlab.ui.control.DropDown
        Label_2         matlab.ui.control.Label
        UIAxes2         matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        Device      % 硬件通信适配器实例
        ForceLines  % 存储 10 条 AnimatedLine 的数组
        DataBuffer  % 真实数据缓存
        StartTime   % 记录实验开始的相对时间

        % 传感器映射字典：定义食指、中指、无名指对应的上下传感器索引 (在 10 个数据通道中的位置)
        % 假设格式: [Flexion_Index, Extension_Index]
        SensorMap = containers.Map({'Index', 'Middle', 'Ring'}, {[1, 6], [2, 7], [3, 8]});

        % MVC 缓存字典：存储各手指测得的最大随意收缩力绝对值
        MvcCache = containers.Map({'Index', 'Middle', 'Ring'}, {1.0, 1.0, 1.0}); % 初始占位防除零

        CurrentTaskFinger = 'Index';  % 当前选中的手指
        CurrentTaskType = 'Trapezoid'; % 当前任务类型

        TargetCurveLine  % 目标曲线对象 (虚线)
        TrackingLine     % 实时跟踪曲线对象 (实线)
    end
    
    methods (Access = private)
        
        function handleIncomingData(app, data)
            app.DataBuffer = [app.DataBuffer; data];
            
            if app.StartTime == -1
                app.StartTime = data(1);
            end
            currentTime = (data(1) - app.StartTime) / 1000; 
            
            forces = data(3:12);
            for i = 1:10
                addpoints(app.ForceLines(i), currentTime, forces(i));
            end
            
            if currentTime > 10
                app.UIAxes.XLim = [currentTime - 10, currentTime];
            end
            
            drawnow limitrate;
        end
        
        function generateTargetCurve(app)
            % 清空原有曲线
            clearpoints(app.TargetCurveLine);
            clearpoints(app.TrackingLine);

            if strcmp(app.CurrentTaskType, 'Trapezoid')
                app.UIAxesExperiment.XLim = [0, 25];
                % 梯形波时间与幅值节点 (对应你图中的 10%, 20%, 30% 阶梯)
                t_nodes = [0, 1, 2, 5, 6, 8.5, 9.5, 12.5, 14, 17, 18, 21, 23, 25];
                f_nodes = [0, 0, 10, 10, 0, 0, 20, 20, 0, 0, 30, 30, 0, 0];

            elseif strcmp(app.CurrentTaskType, 'Triangle')
                app.UIAxesExperiment.XLim = [0, 20];
                % 三角波时间与幅值节点 (正负 20%)
                t_nodes = [0, 5, 10, 15, 20];
                f_nodes = [0, 20, 0, -20, 0];
            end

            % 将预设点画在背景图层上 (黑色虚线)
            app.TargetCurveLine.LineStyle = '--';
            app.TargetCurveLine.Color = [0.5 0.5 0.5];
            app.TargetCurveLine.LineWidth = 2;
            addpoints(app.TargetCurveLine, t_nodes, f_nodes);
        end

        function handleIncomingData(app, data)
            % data 格式: [Timestamp, Angle, L1..L5, R1..R5] (共 12 位)

            % --- 原有的 10 路数据缓存与保存逻辑保持不变 (后台静默记录完整数据) ---
            app.DataBuffer = [app.DataBuffer; data];

            if app.StartTime == -1
                app.StartTime = data(1);
            end
            currentTime = (data(1) - app.StartTime) / 1000;

            % 如果当前超过 Trial 最大时长 (如 25s)，自动触发停止函数
            if currentTime > app.UIAxesExperiment.XLim(2)
                app.StopButtonPushed();
                return;
            end

            % --- 新增的力示踪计算逻辑 ---
            % 获取当前目标手指的传感器映射 (例如食指对应通道 1 和 通道 6)
            sensorIdx = app.SensorMap(app.CurrentTaskFinger);

            % 提取力值 (注意索引偏移：data(1)是时间，(2)是角度，力从(3)开始)
            flexionForce = data(2 + sensorIdx(1));
            extensionForce = data(2 + sensorIdx(2));

            % 计算净力 (下压为正，上抬为负。需根据你的硬件标定正负号)
            netForce = flexionForce - extensionForce;

            if strcmp(app.CurrentTaskType, 'MVC_Calibration')
                % 如果处于标定模式，实时更新 MVC 缓存中的最大值
                currentMax = app.MvcCache(app.CurrentTaskFinger);
                app.MvcCache(app.CurrentTaskFinger) = max(currentMax, abs(netForce));
                % UI 提示当前 MVC 峰值...
            else
                % 如果处于跟踪模式，计算 % MVC 并绘图
                mvcValue = app.MvcCache(app.CurrentTaskFinger);
                percentMvc = (netForce / mvcValue) * 100;

                % 将实时的 % MVC 数据点追加到追踪曲线上
                addpoints(app.TrackingLine, currentTime, percentMvc);
            end

            drawnow limitrate;
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % 扫描串口
            ports = serialportlist("available");
            if isempty(ports)
                app.PortDropDown.Items = {'无可用串口'};
                app.StatusLabel.Text = '状态: 未检测到设备';
            else
                app.PortDropDown.Items = ports;
                app.StatusLabel.Text = '状态: 等待连接';
            end
            
            % 初始化图表
            app.UIAxes.XLim = [0, 10]; 
            app.UIAxes.XGrid = 'on'; app.UIAxes.YGrid = 'on';
            
            % 预分配波形线
            colors = lines(10);
            app.ForceLines = gobjects(1, 10);
            for i = 1:10
                app.ForceLines(i) = animatedline(app.UIAxes, 'Color', colors(i,:), 'LineWidth', 1.2);
            end
            
            app.DataBuffer = [];
            app.SyncLamp.Color = [0.8 0.8 0.8];
        end

        % Callback function
        function UIAxesButtonDown(app, event)
            
        end

        % Button pushed function: StopButton
        function StopButtonPushed2(app, event)
            if isempty(app.Device), return; end
            
            app.Device.StopRecording();
            app.SyncLamp.Color = [0.8 0.8 0.8];
            app.StatusLabel.Text = '状态: 停止，正在保存数据';
            
            if ~isempty(app.DataBuffer)
                filename = sprintf('Experiment_%s.csv', datestr(now, 'yyyymmdd_HHMMSS'));
                T = array2table(app.DataBuffer, 'VariableNames', ...
                    {'Timestamp_ms', 'Angle', 'L1','L2','L3','L4','L5','R1','R2','R3','R4','R5'});
                writetable(T, filename);
                app.StatusLabel.Text = ['状态: 数据已存至 ', filename];
            end
        end

        % Button pushed function: ConnectButton
        function ConnectButtonPushed(app, event)
            if strcmp(app.ConnectButton.Text, '连接设备')
                port = app.PortDropDown.Value;
                if strcmp(port, '无可用串口'), return; end
                
                try
                    app.Device = SerialDevice(port, 115200);
                    % 绑定底层数据驱动到自定义处理函数
                    app.Device.DataCallback = @app.handleIncomingData;
                    
                    app.ConnectButton.Text = '断开连接';
                    app.StatusLabel.Text = ['状态: 已连接至 ', char(port)];
                    app.PortDropDown.Enable = 'off';
                catch ME
                    uialert(app.UIFigure, ME.message, '连接失败');
                end
            else
                delete(app.Device);
                app.Device = [];
                app.ConnectButton.Text = '连接设备';
                app.StatusLabel.Text = '状态: 已断开';
                app.PortDropDown.Enable = 'on';
            end
        end

        % Button pushed function: StartButton
        function StartButtonPushed(app, event)
            if isempty(app.Device), return; end
            
            app.DataBuffer = [];
            for i = 1:10, clearpoints(app.ForceLines(i)); end
            app.StartTime = -1;
            app.UIAxes.XLim = [0, 10];
            
            app.Device.StartRecording();
            app.SyncLamp.Color = 'green';
            app.StatusLabel.Text = '状态: 正在采集...';
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.DebugTab = uitab(app.TabGroup);
            app.DebugTab.Title = 'Debug';
            app.UIAxes = uiaxes(app.DebugTab);
            title(app.UIAxes, '10路传感器实时波形')
            xlabel(app.UIAxes, '时间(s)')
            ylabel(app.UIAxes, '读取值')
            zlabel(app.UIAxes, 'Z')
            app.StartButton = uibutton(app.DebugTab, 'push');
            app.StartButton.ButtonPushedFcn = createCallbackFcn(app, @StartButtonPushed, true);
            app.StartButton.Text = 'StartButton';
            app.StopButton = uibutton(app.DebugTab, 'push');
            app.StopButton.ButtonPushedFcn = createCallbackFcn(app, @StopButtonPushed2, true);
            app.StopButton.Text = 'StopButton';
            app.StatusLabel = uilabel(app.DebugTab);
            app.StatusLabel.Text = '状态：等待连接...';
            app.ConnectButton = uibutton(app.DebugTab, 'push');
            app.ConnectButton.ButtonPushedFcn = createCallbackFcn(app, @ConnectButtonPushed, true);
            app.ConnectButton.Text = '连接设备';
            app.GPIOLampLabel = uilabel(app.DebugTab);
            app.GPIOLampLabel.Text = 'GPIO同步信号';
            app.SyncLamp = uilamp(app.DebugTab);
            app.Label = uilabel(app.DebugTab);
            app.Label.Text = '串口号';
            app.PortDropDown = uidropdown(app.DebugTab);
            app.ExperimentTab = uitab(app.TabGroup);
            app.ExperimentTab.Title = 'Experiment';
            app.UIAxes2 = uiaxes(app.ExperimentTab);
            title(app.UIAxes2, '单指力追踪（% MVC）')
            xlabel(app.UIAxes2, 'X')
            ylabel(app.UIAxes2, 'Y')
            zlabel(app.UIAxes2, 'Z')
            app.UIAxes2.XTick = [0 0.1 0.2 0.3 0.4 0.5 0.6 0.7 0.8 0.9 1];
            app.UIAxes2.YTick = [0 0.1 0.2 0.3 0.4 0.5 0.6 0.7 0.8 0.9 1];
            app.Label_2 = uilabel(app.ExperimentTab);
            app.Label_2.Text = '目标手指';
            app.FingerDropDown = uidropdown(app.ExperimentTab);
            app.FingerDropDown.Items = {'Index', 'Middle', 'Ring'};
            app.FingerDropDown.Value = 'Index';
            app.Label_3 = uilabel(app.ExperimentTab);
            app.Label_3.Text = '实验模式';
            app.ModeDropDown = uidropdown(app.ExperimentTab);
            app.ModeDropDown.Items = {'MVC标定', '力跟踪'};
            app.ModeDropDown.Value = 'MVC标定';
            app.Label_4 = uilabel(app.ExperimentTab);
            app.Label_4.Text = '测得实验值';
            app.MvcEditField = uieditfield(app.ExperimentTab, 'numeric');
            app.StartButton_2 = uibutton(app.ExperimentTab, 'push');
            app.StartButton_2.Text = 'StartButton';
            app.StopButton_2 = uibutton(app.ExperimentTab, 'push');
            app.StopButton_2.Text = 'StopButton';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end